classdef RecreaText < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BildauswaehlenButton  matlab.ui.control.Button
        GOButton              matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Picture % chosen picture from imread
        PicName % path of the chosen picture
    end
    
    methods (Access = private)
        
        function results = main(app)
            app.Picture = BinaryImage.imageToBinary(app.Picture);
            app.Picture = EdgeDetector.cannyFilter(app.Picture);
            imshow(app.Picture, 'Parent', app.UIAxes);
            results = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BildauswaehlenButton
        function BildauswaehlenButtonPushed(app, event)
            [app.PicName, Path_Name] = uigetfile('*.*');
            app.Picture = imread(['assets\' app.PicName]);
            imshow(app.Picture, 'Parent', app.UIAxes);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            image = main.mainFunc();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BildauswaehlenButton = uibutton(app.UIFigure, 'push');
            app.BildauswaehlenButton.ButtonPushedFcn = createCallbackFcn(app, @BildauswaehlenButtonPushed, true);
            app.BildauswaehlenButton.Text = 'Bild auswaehlen';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RecreaText

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end